classdef PGZ_decoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        decodedcodewordEditField       matlab.ui.control.EditField
        decodedcodewordEditFieldLabel  matlab.ui.control.Label
        syndromesEditField             matlab.ui.control.EditField
        syndromesEditFieldLabel        matlab.ui.control.Label
        addederrorEditField            matlab.ui.control.EditField
        addederrorEditFieldLabel       matlab.ui.control.Label
        codewordEditField              matlab.ui.control.EditField
        codewordEditFieldLabel         matlab.ui.control.Label
        informationvectorEditField     matlab.ui.control.EditField
        informationvectorEditFieldLabel  matlab.ui.control.Label
        LetsgoButton                   matlab.ui.control.Button
        OutputFormatDropDown           matlab.ui.control.DropDown
        OutputFormatLabel              matlab.ui.control.Label
        SeedcEditField                 matlab.ui.control.NumericEditField
        SeedcEditFieldLabel            matlab.ui.control.Label
        SeedeEditField                 matlab.ui.control.NumericEditField
        SeedeEditFieldLabel            matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        qEditField                     matlab.ui.control.NumericEditField
        qEditFieldLabel                matlab.ui.control.Label
        PetersonGorensteinZierlerAlgorithmDecoderLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        GenerateButton    matlab.ui.control.Button
        SeedEditField     matlab.ui.control.NumericEditField
        SeedEditFieldLabel matlab.ui.control.Label
        InfoTextArea      matlab.ui.control.TextArea
        CodedTextArea     matlab.ui.control.TextArea
        ErrorTextArea     matlab.ui.control.TextArea
        DecodedTextArea   matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsgoButton
        function LetsgoButtonPushed(app, event)

            % if ~app.cxCheckBox.Value && ~app.cCheckBox.Value && ~app.cCheckBox_2.Value
            %
            %     disp('Please check at least one checkbox.');
            %     app.TextArea.Value = 'Please check at least one checkbox.';
            %     app.EditField.FontColor = [1 0 0]; % Red color
            %
            %     return;
            % end
            app.TextArea.Value = '';
            q = app.qEditField.Value;
            d = app.dEditField.Value;
            N = app.NEditField.Value;
            disp_mode = app.OutputFormatDropDown.Value;
            if q<=2
                app.TextArea.Value = 'Incorrect combination of input parameters: q cannot be smaller than 2';
                app.TextArea.FontColor = [1 0 0]; % Red color
                app.qEditField.FontColor = [1 0 0];
            elseif N ~= round(N)
                app.TextArea.Value = 'N should be an integer';
                app.codewordEditField.Value = '';
                app.informationvectorEditField.Value = '';
                app.addederrorEditField.Value = '';
                app.decodedcodewordEditField.Value ='';
                app.syndromesEditField.Value = '';
                app.TextArea.FontColor = [1 0 0]; % Red color
                app.NEditField.FontColor = [1 0 0];
            elseif d ~= round(d)
                app.TextArea.Value = 'd should be an integer';
                app.codewordEditField.Value = '';
                app.informationvectorEditField.Value = '';
                app.addederrorEditField.Value = '';
                app.decodedcodewordEditField.Value ='';
                app.syndromesEditField.Value = '';
                app.TextArea.FontColor = [1 0 0]; % Red color
                app.dEditField.FontColor = [1 0 0];
            elseif q ~= round(q)
                app.TextArea.Value = 'q should be an integer';
                app.codewordEditField.Value = '';
                app.informationvectorEditField.Value = '';
                app.addederrorEditField.Value = '';
                app.decodedcodewordEditField.Value ='';
                app.syndromesEditField.Value = '';
                app.TextArea.FontColor = [1 0 0]; % Red color
                app.qEditField.FontColor = [1 0 0];
            elseif N<0
                app.TextArea.Value = 'Please choose N>=0';
                app.codewordEditField.Value = '';
                app.informationvectorEditField.Value = '';
                app.addederrorEditField.Value = '';
                app.decodedcodewordEditField.Value ='';
                app.syndromesEditField.Value = '';
                app.TextArea.FontColor = [1 0.5 0]; % Orange color
                app.NEditField.FontColor = [1 0 0];
                app.dEditField.FontColor = [0 0 0];
                app.qEditField.FontColor = [0 0 0];
            else
                m = ceil(log2(q));
                alpha = gf(2, m);
                n = q-1;
                k = n-d+1;
                t = floor((d-1)/2);
                seedc = app.SeedcEditField.Value;
                seede = app.SeedeEditField.Value;

                if d > n
                    app.TextArea.Value = 'Incorrect combination of input parameters: d cannot be larger than q-1';
                    app.codewordEditField.Value = '';
                    app.informationvectorEditField.Value = '';
                    app.addederrorEditField.Value = '';
                    app.decodedcodewordEditField.Value ='';
                    app.syndromesEditField.Value = '';
                    app.TextArea.FontColor = [1 0 0]; % Red color
                    app.NEditField.FontColor = [0 0 0];
                    app.dEditField.FontColor = [1 0 0];
                    app.qEditField.FontColor = [1 0 0];
                elseif d<=0
                    app.TextArea.Value = 'Incorrect combination of input parameters: d cannot be smaller than 1';
                    app.codewordEditField.Value = '';
                    app.informationvectorEditField.Value = '';
                    app.addederrorEditField.Value = '';
                    app.decodedcodewordEditField.Value ='';
                    app.syndromesEditField.Value = '';
                    app.TextArea.FontColor = [1 0 0]; % Red color
                    app.NEditField.FontColor = [0 0 0];
                    app.dEditField.FontColor = [1 0 0];
                    app.qEditField.FontColor = [0 0 0];
                elseif N > n
                    app.TextArea.Value = 'Incorrect combination of input parameters: N cannot be larger than q-1';
                    app.TextArea.FontColor = [1 0 0]; % Red color
                    app.NEditField.FontColor = [1 0 0];
                    app.qEditField.FontColor = [1 0 0];
                else
                    % Perform encoding and decoding

                    [a, c] = codewort_generator(alpha, q, m, n, k, t, seedc);
                    [v, e] = received_cw_generator(c, n, q, m, N, seede);

                    %% Decoding
                    [S, tm_e] = syndrome_evaluation(alpha, t, m, n, v);            % Syndrome evaluation
                    no_solution = 0; short_lamb = 0;
                    if tm_e
                        [Lambda, no_solution] = error_locator_polynomial(S, m, t); % Get coefficients of error locator polynomial
                    else
                        e_red = gf(0,m);      % If there was no error while transmitting --> e(x) = 0
                    end

                    if tm_e && ~no_solution
                        [e_red, short_lamb] = calc_error_poly(Lambda, S, alpha, m, n);     % Calculate error polynomial
                    end
                    % Reconstruct cw and extract information bits
                    c_red = v-e_red;
                    a_red = c_red.x(1:k);

                    app.codewordEditField.Value = gf_to_string(c, alpha, m, disp_mode);
                    app.informationvectorEditField.Value = gf_to_string(gf(a, m), alpha, m, disp_mode);
                    app.addederrorEditField.Value = gf_to_string(e, alpha, m, disp_mode);
                    app.decodedcodewordEditField.Value = gf_to_string(c_red, alpha, m, disp_mode);
                    app.syndromesEditField.Value =  gf_to_string(S, alpha, m, disp_mode);

                    app.NEditField.FontColor = [0 0 0];
                    app.dEditField.FontColor = [0 0 0];
                    app.qEditField.FontColor = [0 0 0];
                    app.TextArea.FontColor = [0 0 0];

                    if no_solution || short_lamb
                        app.TextArea.Value = 'Decoding error';
                        app.decodedcodewordEditField.Value ='';
                        %app.TextArea.FontColor = [1 0 0];
                    elseif ~tm_e
                        if all(c==c_red)
                            app.TextArea.Value = {'Immediate decoding (All syndromes are zero)', 'Successful decoding (decoded codeword is equal to original codeword)'};
                        else
                            app.TextArea.Value = {'Immediate decoding (All syndromes are zero)', 'Undetected error (decoded codeword is NOT equal to original codeword)'};
                        end
                    elseif ~all(c==c_red)
                        app.TextArea.Value = {'Decoding through the algorithm','Undetected error (decoded codeword is NOT equal to original codeword)'};
                        %app.TextArea.FontColor = [1 0 0];
                    else
                        app.TextArea.Value = {'Decoding through the algorithm', 'Successful decoding (decoded codeword is equal to original codeword)'};
                        %app.TextArea.FontColor = [1 1 1];
                    end
                end
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Key, 'return')
                % Call the button's callback
                app.LetsgoButtonPushed();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.PetersonGorensteinZierlerAlgorithmDecoderLabel = uilabel(app.UIFigure);
            app.PetersonGorensteinZierlerAlgorithmDecoderLabel.Text = 'Peterson-Gorenstein-Zierler Algorithm Decoder';
            app.qEditFieldLabel = uilabel(app.UIFigure);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.UIFigure, 'numeric');
            app.qEditField.Value = 8;
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 3;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 1;
            app.SeedeEditFieldLabel = uilabel(app.UIFigure);
            app.SeedeEditFieldLabel.Text = 'Seed e';
            app.SeedeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedeEditField.Value = 42;
            app.SeedcEditFieldLabel = uilabel(app.UIFigure);
            app.SeedcEditFieldLabel.Text = 'Seed c';
            app.SeedcEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedcEditField.Value = 42;
            app.OutputFormatLabel = uilabel(app.UIFigure);
            app.OutputFormatLabel.Text = 'Output Format';
            app.OutputFormatDropDown = uidropdown(app.UIFigure);
            app.OutputFormatDropDown.Items = {'Polynomial', 'Vector of powers of alpha', 'Binary vector'};
            app.OutputFormatDropDown.ItemsData = {'1', '2', '3'};
            app.OutputFormatDropDown.Value = '1';
            app.LetsgoButton = uibutton(app.UIFigure, 'push');
            app.LetsgoButton.ButtonPushedFcn = createCallbackFcn(app, @LetsgoButtonPushed, true);
            app.LetsgoButton.Text = 'Lets go';
            app.informationvectorEditFieldLabel = uilabel(app.UIFigure);
            app.informationvectorEditFieldLabel.Text = 'information vector';
            app.informationvectorEditField = uieditfield(app.UIFigure, 'text');
            app.codewordEditFieldLabel = uilabel(app.UIFigure);
            app.codewordEditFieldLabel.Text = 'codeword';
            app.codewordEditField = uieditfield(app.UIFigure, 'text');
            app.addederrorEditFieldLabel = uilabel(app.UIFigure);
            app.addederrorEditFieldLabel.Text = 'added error';
            app.addederrorEditField = uieditfield(app.UIFigure, 'text');
            app.syndromesEditFieldLabel = uilabel(app.UIFigure);
            app.syndromesEditFieldLabel.Text = 'syndromes';
            app.syndromesEditField = uieditfield(app.UIFigure, 'text');
            app.decodedcodewordEditFieldLabel = uilabel(app.UIFigure);
            app.decodedcodewordEditFieldLabel.Text = 'decoded codeword';
            app.decodedcodewordEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>>';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PGZ_decoder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end